classdef GUI_GlobalBioIm < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        GlobalBioImGUIUIFigure  matlab.ui.Figure
        TabGroup                matlab.ui.container.TabGroup
        Main                    matlab.ui.container.Tab
        ForwardModel            matlab.ui.container.Tab
        TextArea_ForwardModel   matlab.ui.control.TextArea
        LabelTextArea           matlab.ui.control.Label
        Button_AddLinOp         matlab.ui.control.Button
        Button_SeeDoc           matlab.ui.control.Button
        LabelDropDown           matlab.ui.control.Label
        DropDown_SelectLinOp    matlab.ui.control.DropDown
        Next                    matlab.ui.control.Button
        FMPanel                 matlab.ui.container.Panel
        CostFunction            matlab.ui.container.Tab
        Optimization            matlab.ui.container.Tab
    end


    properties (Access = public)

        % Components progamatically added in ForwardModel panel  
        LinOpCell={}   % Cell array containing the LinOps
        test
    end
    
    properties (Access = private)
      
        % Global properties
        doc_weblink = 'biomedical-imaging-group.github.io/GlobalBioIm/'; % Link to online documentation 
        
        % Properties for ForwardModel panel  
        linOps_order     = [];     % order of selected linOps 
        linOps_list      = {};     % list of selected linOps 
        maxLinOp         = 6;      % maximum number of concatenable linOps
        linOpText        = [];     % info displayed in text area for user 

        
        % Information for display 
        pos_left      = 50; 
        pos_bott_init = 325;
        incr_height   = 50;
        def_width     = 30; 
        def_height    = 20;
        
    end

    methods (Access = private)
        
        % FORWARD MODEL PANEL ------------------
        
        % Set list of linOps in dropdown menu
        function ListOfLinOpsStr = getStringListOfAvailableLinOps(app)
            dirList1 = struct2cell(dir('../LinOp/LinOp*.m')); 
            dirList2 = struct2cell(dir('../LinOp/SelectorLinOps/LinOp*.m')); 
            dirList = [dirList1 dirList2]; 
            ListOfLinOpsStr = strrep(strrep(dirList(1,:),'.m',''),'LinOp','');  
        end
        
        % Delete hierarchy of linOps
        function deleteHierarchyLinOps(app)
            % Number of LinOps
            nLinOps = length(app.LinOpCell);
            
            % Loop over all the selected LinOps
            for idx=1:nLinOps
                delete(app.LinOpCell{idx}.label);
                delete(app.LinOpCell{idx}.butProp);
                delete(app.LinOpCell{idx}.butDel);
                delete(app.LinOpCell{idx}.ddlabel);
                delete(app.LinOpCell{idx}.butUp); 
                delete(app.LinOpCell{idx}.butDown); 
            end
        end 
        
        % Create hierarchy of linOps
        function createHierarchyLinOps(app)
            
           % Number of LinOps
           nLinOps = length(app.LinOpCell);
          
           % Loop over all the selected LinOps
           app.linOpText = ['H = '];
           for idx=1:nLinOps
               % Position info
               pos_bott      = app.pos_bott_init-(idx-1)*app.incr_height;
               
               % Add linOp name info
               app.LinOpCell{idx}.label = uilabel(app.ForwardModel);
               app.LinOpCell{idx}.label.Text = ['LinOp' ,app.LinOpCell{idx}.name];
               app.LinOpCell{idx}.label.Position = [app.pos_left+40 pos_bott app.def_width*4 app.def_height];
               
               % Update text for concatenation of linOps
               app.linOpText = [app.linOpText ' H' char(8320+idx)];
               
               % Create label of drop-down component
               app.LinOpCell{idx}.ddlabel = uilabel(app.ForwardModel);
               app.LinOpCell{idx}.ddlabel.Position = [app.pos_left pos_bott app.def_width*1.5 app.def_height];
               app.LinOpCell{idx}.ddlabel.FontWeight = 'bold';
               app.LinOpCell{idx}.ddlabel.FontSize = 16;
               app.LinOpCell{idx}.ddlabel.Text = ['H' char(8320+idx) ' : '];
               
               % Create button for setting properties
               % NOTE: HERE WE NEED TO ADD THE OPEN WINDOWS OPTION
               app.LinOpCell{idx}.butProp = uibutton(app.ForwardModel, 'push');
               app.LinOpCell{idx}.butProp.Text = 'Set Properties';
               app.LinOpCell{idx}.butProp.FontWeight = 'bold';
               app.LinOpCell{idx}.butProp.Position = [app.pos_left+180 pos_bott app.def_width*3.2 app.def_height];
               app.LinOpCell{idx}.butProp.ButtonPushedFcn = createCallbackFcn(app, @(app,event) Button_PropButtonPushed(app,idx), true);
               
               % Create delete button
               app.LinOpCell{idx}.butDel = uibutton(app.ForwardModel, 'push');
               app.LinOpCell{idx}.butDel.Text = '';
               app.LinOpCell{idx}.butDel.Icon = 'trashIcon.png';
               app.LinOpCell{idx}.butDel.Position = [app.pos_left+340 pos_bott app.def_width*0.8 app.def_height];
               app.LinOpCell{idx}.butDel.ButtonPushedFcn = createCallbackFcn(app, @(app,event) Button_DelLinOpButtonPushed(app,idx), true);
               
               % Create up button
               app.LinOpCell{idx}.butUp = uibutton(app.ForwardModel, 'push');
               app.LinOpCell{idx}.butUp.Text = '';
               app.LinOpCell{idx}.butUp.Icon = 'upArrow.png';
               app.LinOpCell{idx}.butUp.Position = [app.pos_left+310 pos_bott app.def_width*0.8 app.def_height];
               app.LinOpCell{idx}.butUp.ButtonPushedFcn = createCallbackFcn(app, @(app,event) Button_UpLinOpButtonPushed(app,idx), true);
                         
               % Create down button
               app.LinOpCell{idx}.butDown = uibutton(app.ForwardModel, 'push');
               app.LinOpCell{idx}.butDown.Text = '';
               app.LinOpCell{idx}.butDown.Icon = 'downArrow.png';
               app.LinOpCell{idx}.butDown.Position = [app.pos_left+285 pos_bott app.def_width*0.8 app.def_height];
               app.LinOpCell{idx}.butDown.ButtonPushedFcn = createCallbackFcn(app, @(app,event) Button_DownLinOpButtonPushed(app,idx), true);
               
           end
           if nLinOps>0
               app.LinOpCell{1}.butUp.Enable = 'off';
               app.LinOpCell{nLinOps}.butDown.Enable = 'off';
           end
           
           % Update text for concatenation of linOps
           app.TextArea_ForwardModel.Value = {app.linOpText};

        end 
    
        % Button pushed function: Button_PropButtonPushed
        function Button_PropButtonPushed(app,idx)
            app.LinOpCell{idx}.SetPropGUI = uifigure;         
            app.LinOpCell{idx}.SetPropGUI.Position  = app.GlobalBioImGUIUIFigure.Position  +  [app.GlobalBioImGUIUIFigure.Position(3) 0 0 0];
            app.LinOpCell{idx}.SetPropGUI.Name  = ['Set Properties ',app.LinOpCell{idx}.name];
        end
        
        
        % Button pushed function: Button_DelLinOpButtonPushed
        function Button_DelLinOpButtonPushed(app,idx)
            
            % Delete previously displayed text in the hierarchy box
            if ~isempty(app.LinOpCell)
                deleteHierarchyLinOps(app);
            end
            
            % Add linOp name info
            tmp=app.LinOpCell;
            app.LinOpCell={};
            pos=1;
            for ii=1:length(tmp)
                if ii~=idx
                    app.LinOpCell{pos}=tmp{ii};
                    pos=pos+1;
                end
            end
            
            % Display updated text in the hierarchy box
            createHierarchyLinOps(app); 
            
            % Enable addLinOp button if max number of linOps not reached
            if length(app.LinOpCell)<app.maxLinOp
                app.Button_AddLinOp.Enable = 'on';
            end
        end
    
        % Button pushed function: Button_UpLinOpButtonPushed
        function Button_UpLinOpButtonPushed(app,idx)
            
            % Delete previously displayed text in the hierarchy box
            if ~isempty(app.LinOpCell)
                deleteHierarchyLinOps(app);
            end
            
            % Reorganize the LinOpCell
            tmp=app.LinOpCell{idx-1};
            app.LinOpCell{idx-1}=app.LinOpCell{idx};
            app.LinOpCell{idx}=tmp;
            
            % Display updated text in the hierarchy box
            createHierarchyLinOps(app); 
        end
    
        % Button pushed function: Button_DownLinOpButtonPushed
        function Button_DownLinOpButtonPushed(app,idx)
            
            % Delete previously displayed text in the hierarchy box
            if ~isempty(app.LinOpCell)
                deleteHierarchyLinOps(app);
            end
            
            % Reorganize the LinOpCell
            tmp=app.LinOpCell{idx+1};
            app.LinOpCell{idx+1}=app.LinOpCell{idx};
            app.LinOpCell{idx}=tmp;
            
            % Display updated text in the hierarchy box
            createHierarchyLinOps(app); 
        end
    
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            % GENERAL ---------------
            
            % MAIN PANEL --------------
            
            % FORWARD MODEL PANEL ------------------
            ListOfLinOpsStr = getStringListOfAvailableLinOps(app); 
            app.DropDown_SelectLinOp.Items = ListOfLinOpsStr; 

        end

        % Button pushed function: Button_AddLinOp
        function Button_AddLinOpButtonPushed(app, event)
            
           % Delete previously displayed text in the hierarchy box 
           if ~isempty(app.LinOpCell)
               deleteHierarchyLinOps(app); 
           end 
            
           % Add linOp name info
           app.LinOpCell{length(app.LinOpCell)+1}.name=app.DropDown_SelectLinOp.Value;          
           
           % Display updated text in the hierarchy box 
           createHierarchyLinOps(app); 
           
           % Disable addLinOp button if max number of linOps reached
           if length(app.LinOpCell)>=app.maxLinOp 
              app.Button_AddLinOp.Enable = 'off'; 
           end
           
        end

        % Button pushed function: Button_SeeDoc
        function Button_SeeDocButtonPushed(app, event)
           linOpName = app.DropDown_SelectLinOp.Value;
           web([app.doc_weblink 'linop.html#linop' lower(linOpName)], '-notoolbar'); 
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.GlobalBioImGUIUIFigure = uifigure;
            app.GlobalBioImGUIUIFigure.Name = 'GlobalBioIm - GUI';
            app.TabGroup = uitabgroup(app.GlobalBioImGUIUIFigure);
            app.Main = uitab(app.TabGroup);
            app.Main.Title = 'Main';
            app.ForwardModel = uitab(app.TabGroup);
            app.ForwardModel.Title = 'Forward Model';
            app.TextArea_ForwardModel = uitextarea(app.ForwardModel);
            app.LabelTextArea = uilabel(app.ForwardModel);
            app.LabelTextArea.Text = 'Forward Model';
            app.Button_AddLinOp = uibutton(app.ForwardModel, 'push');
            app.Button_AddLinOp.ButtonPushedFcn = createCallbackFcn(app, @Button_AddLinOpButtonPushed, true);
            app.Button_AddLinOp.Text = 'Add LinOp';
            app.Button_SeeDoc = uibutton(app.ForwardModel, 'push');
            app.Button_SeeDoc.ButtonPushedFcn = createCallbackFcn(app, @Button_SeeDocButtonPushed, true);
            app.Button_SeeDoc.Text = 'See Doc';
            app.LabelDropDown = uilabel(app.ForwardModel);
            app.LabelDropDown.Text = 'Select a LinOp:';
            app.DropDown_SelectLinOp = uidropdown(app.ForwardModel);
            app.DropDown_SelectLinOp.Items = {'Test', 'Option 5', 'Test2', 'Option 3', 'Option 4'};
            app.DropDown_SelectLinOp.Value = 'Test';
            app.Next = uibutton(app.ForwardModel, 'push');
            app.Next.Text = 'Next';
            app.FMPanel = uipanel(app.ForwardModel);
            app.FMPanel.Title = 'Hierarchy of Selected LinOps';
            app.CostFunction = uitab(app.TabGroup);
            app.CostFunction.Title = 'Cost Function';
            app.Optimization = uitab(app.TabGroup);
            app.Optimization.Title = 'Optimization';
        end
    end

    methods (Access = public)

        % Construct app
        function app = GUI_GlobalBioIm

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.GlobalBioImGUIUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.GlobalBioImGUIUIFigure)
        end
    end
end